classdef Browniano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SDdeladiferencialogaritmicaEditField  matlab.ui.control.NumericEditField
        SDdeladiferencialogaritmicaEditFieldLabel  matlab.ui.control.Label
        SDderendimientosEditField       matlab.ui.control.NumericEditField
        SDderendimientosEditFieldLabel  matlab.ui.control.Label
        PromedioderendimientosEditField  matlab.ui.control.NumericEditField
        PromedioderendimientosEditFieldLabel  matlab.ui.control.Label
        NdesimulacionesEditField        matlab.ui.control.NumericEditField
        NdesimulacionesEditFieldLabel   matlab.ui.control.Label
        AccionesDropDown                matlab.ui.control.DropDown
        AccionesDropDownLabel           matlab.ui.control.Label
        GenerarBrawnianoButton          matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NumSimulations = 10; % Valor inicial
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Inicio(app, event)
            global Matriz_Accion

            archivo_nom = 'Acciones_new_2024.mat';    
            directorio_inicio = pwd;
            ruta_datos_entrada = fullfile(directorio_inicio, archivo_nom);
            % Cargar los datos desde el archivo .mat
            try
                datos = load(ruta_datos_entrada, 'Matriz_Accion', 'Lista_Acciones');
                Matriz_Accion = datos.Matriz_Accion;
                Lista_Acciones = datos.Lista_Acciones;
        
                % Configurar el Dropdown con los nombres de las acciones
                app.AccionesDropDown.Items = Lista_Acciones;
        
            catch ME
                uialert(app.UIFigure, ['Error al cargar datos: ', ME.message], 'Error');
            end

        end

        % Button pushed function: GenerarBrawnianoButton
        function GenerarBrawnianoButtonPushed(app, event)
            figura = app.UIAxes;
            cla(figura, "reset");

            %Acceder al valor númerico del campo
            numSimulations = app.NdesimulacionesEditField.Value;

            if numSimulations <= 0 || floor(numSimulations) ~= numSimulations
                uialert(app.UIFigure, 'El número de simulaciones debe ser un entero positivo.', 'Error');
            return;
            end

            for i =1:numSimulations
                grafica = Brow(4);
                figuras = app.UIAxes;
                hold (figuras,'on');            
                plot(figuras,grafica);
            end
        end

        % Callback function
        function Acciones(app, event)
             
        end

        % Value changed function: AccionesDropDown
        function SeleccionAcc(app, event)
            global Matriz_Accion
            valor = app.AccionesDropDown.Value;
        
            % Validar estructura de Matriz_Accion
            if iscell(Matriz_Accion)
                nombres = cellfun(@(x) x.Nombre, Matriz_Accion, 'UniformOutput', false);
            elseif isstruct(Matriz_Accion)
                nombres = {Matriz_Accion.Nombre};
            else
                uialert(app.UIFigure, 'Estructura de Matriz_Accion no válida.', 'Error');
                return;
            end
        
            % Buscar índice
            ind_fin = find(strcmp(valor, nombres), 1);
        
            if isempty(ind_fin)
                uialert(app.UIFigure, 'La acción seleccionada no tiene datos.', 'Error');
                return;
            end
        
            % Limpiar el gráfico
            cla(app.UIAxes2);
            title(app.UIAxes2, valor);
            xlabel(app.UIAxes2, 'Días');
            ylabel(app.UIAxes2, 'Precio');
        
            % Obtener datos de la acción seleccionada
            datos = Matriz_Accion{ind_fin}.Datos;
        
            if isempty(datos)
                uialert(app.UIFigure, 'No hay datos para la acción seleccionada.', 'Error');
                return;
            end
        
            % Calcular y graficar
            plot(app.UIAxes2, datos, 'b');
            xlabel(app.UIAxes2, 'Días');
            ylabel(app.UIAxes2, 'Precio');

            % --- Cálculo del rendimiento promedio y desviación estándar ---
    
            % Rendimiento promedio (como cambio porcentual)
            rendimientos = diff(datos) ./ datos(1:end-1); % Calcular cambios relativos
            
            rendimiento_promedio = mean(rendimientos);  % Calcular el promedio de rendimientos
            desviacion_estandar = std(rendimientos);    % Calcular la desviación estándar
        
            % --- Cálculo de la desviación de la diferencia logarítmica ---
            log_diferencias = diff(log(datos));  % Calcular las diferencias logarítmicas
            desviacion_logaritmica = std(log_diferencias);  % Calcular la desviación estándar logarítmica
            
            % --- Mostrar los resultados en los campos correspondientes ---
            app.PromedioderendimientosEditField.Value = rendimiento_promedio;
            app.SDderendimientosEditField.Value = desviacion_estandar;
            app.SDdeladiferencialogaritmicaEditField.Value = desviacion_logaritmica;


        end

        % Value changed function: NdesimulacionesEditField
        function NdesimulacionesEditFieldValueChanged(app, event)
            value = app.NdesimulacionesEditField.Value;
            app.NumSimulations = value; % Actualiza la propiedad con el valor ingresado
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Browniano')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GenerarBrawnianoButton = uibutton(app.UIFigure, 'push');
            app.GenerarBrawnianoButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarBrawnianoButtonPushed, true);
            app.GenerarBrawnianoButton.Text = 'Generar Brawniano';
            app.AccionesDropDownLabel = uilabel(app.UIFigure);
            app.AccionesDropDownLabel.Text = 'Acciones';
            app.AccionesDropDown = uidropdown(app.UIFigure);
            app.AccionesDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccionAcc, true);
            app.NdesimulacionesEditFieldLabel = uilabel(app.UIFigure);
            app.NdesimulacionesEditFieldLabel.Text = 'N° de simulaciones';
            app.NdesimulacionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NdesimulacionesEditField.ValueChangedFcn = createCallbackFcn(app, @NdesimulacionesEditFieldValueChanged, true);
            app.PromedioderendimientosEditFieldLabel = uilabel(app.UIFigure);
            app.PromedioderendimientosEditFieldLabel.Text = 'Promedio de rendimientos';
            app.PromedioderendimientosEditField = uieditfield(app.UIFigure, 'numeric');
            app.SDderendimientosEditFieldLabel = uilabel(app.UIFigure);
            app.SDderendimientosEditFieldLabel.Text = 'SD de rendimientos';
            app.SDderendimientosEditField = uieditfield(app.UIFigure, 'numeric');
            app.SDdeladiferencialogaritmicaEditFieldLabel = uilabel(app.UIFigure);
            app.SDdeladiferencialogaritmicaEditFieldLabel.Text = 'SD de la diferencia logaritmica';
            app.SDdeladiferencialogaritmicaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Browniano(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Inicio(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end